classdef WBVCalc_About_UI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AboutWBVCalculatorUIFigure  matlab.ui.Figure
        WBVCalculatorBetaPanel      matlab.ui.container.Panel
        TextArea                    matlab.ui.control.TextArea
        Image                       matlab.ui.control.Image
        TextArea_2                  matlab.ui.control.TextArea
        OKButton                    matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, licText, version, mainPos)
            oldPos=app.AboutWBVCalculatorUIFigure.Position;
            app.AboutWBVCalculatorUIFigure.Position=[mainPos(1)+(mainPos(3)-oldPos(3))/2,...
                mainPos(2)+(mainPos(4)-oldPos(4))/2,oldPos(3),oldPos(4)];
            
            app.TextArea_2.Value=["Licensing:",licText];
            app.WBVCalculatorBetaPanel.Title="WBV Calculator Beta Ver. "+version;
        
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            close(app.AboutWBVCalculatorUIFigure);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AboutWBVCalculatorUIFigure = uifigure('Visible', 'off');
            app.AboutWBVCalculatorUIFigure.Name = 'About WBV Calculator';
            app.WBVCalculatorBetaPanel = uipanel(app.AboutWBVCalculatorUIFigure);
            app.WBVCalculatorBetaPanel.Title = 'WBV Calculator Beta';
            app.TextArea = uitextarea(app.WBVCalculatorBetaPanel);
            app.TextArea.Value = {'Please limit usage to the scope agreed upon with developers.'; ' © 2022 - Uppsala University, Sweden  '; ''; 'Developed by:'; 'Pasan Hettiarachchi <pasan.hettiarachchi@medsci.uu.se>'; 'Peter Johansson <peter.johansson@medsci.uu.se>'; ''; ''};
            app.Image = uiimage(app.WBVCalculatorBetaPanel);
            app.Image.ImageSource = 'pond5_fb_icon_rebrand_200px.png';
            app.TextArea_2 = uitextarea(app.WBVCalculatorBetaPanel);
            app.TextArea_2.Value = {'Licensing:'; 'WBV Calculator is running in evaluation mode. For licensing details contact authors.'};
            app.OKButton = uibutton(app.WBVCalculatorBetaPanel, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            % Show the figure after all components are created
            app.AboutWBVCalculatorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = WBVCalc_About_UI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AboutWBVCalculatorUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AboutWBVCalculatorUIFigure)
        end
    end
end